classdef EqualizerXtra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadWAVFilePanel                matlab.ui.container.Panel
        BrowseButton                    matlab.ui.control.Button
        BrowsetoMP3EditField            matlab.ui.control.EditField
        FilterConfigurationPanel        matlab.ui.container.Panel
        LowPassKnobLabel                matlab.ui.control.Label
        LowPassKnob                     matlab.ui.control.Knob
        BandPassKnobLabel               matlab.ui.control.Label
        BandPassKnob                    matlab.ui.control.Knob
        HighPassLabel                   matlab.ui.control.Label
        HighPassKnob                    matlab.ui.control.Knob
        LowPassStopBandEditFieldLabel   matlab.ui.control.Label
        LowPassEditField                matlab.ui.control.NumericEditField
        BandPassStartBandEditFieldLabel  matlab.ui.control.Label
        BandPassStartEditField          matlab.ui.control.NumericEditField
        BandPassStartBandEditField_2Label  matlab.ui.control.Label
        BandPassStopEditField           matlab.ui.control.NumericEditField
        HighPassStartBandEditFieldLabel  matlab.ui.control.Label
        HighPassEditField               matlab.ui.control.NumericEditField
        FrequencySpecificationsLabel    matlab.ui.control.Label
        WeightSpecificationsLabel       matlab.ui.control.Label
        OrderSliderLabel                matlab.ui.control.Label
        OrderSlider                     matlab.ui.control.Slider
        FilterOrderSpecificationsLabel  matlab.ui.control.Label
        FrequencySpectrumPanel          matlab.ui.container.Panel
        SpectrumOriginal                matlab.ui.control.UIAxes
        SpectrumProcessed               matlab.ui.control.UIAxes
        ListeningExperiencePanel        matlab.ui.container.Panel
        PlayOriginalButton              matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        ProcessButton                   matlab.ui.control.Button
        PlayProcessedButton             matlab.ui.control.Button
        PostProcessingOptionsPanel      matlab.ui.container.Panel
        LaunchAmpResponse               matlab.ui.control.Button
        ExportWAV                       matlab.ui.control.Button
        ProgramStatusPanel              matlab.ui.container.Panel
        StatusLabel                     matlab.ui.control.Label
        ECE460EqualizerLabel            matlab.ui.control.Label
        poweredbyMATLABandsleeplessnightsLabel  matlab.ui.control.Label
        DialBuchananSamboluLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        % PRESPECIFY GLOBAL VARIABLES
        
            % Audio file support
            y, yT, Fs;
            
    end
    
    methods (Access = public)
        
        % FILTER VALUES CHANGED
        function void = Changed(app)
            
            % Restrict acces
                app.PlayProcessedButton.Enable = 'off';
                app.LaunchAmpResponse.Enable = 'off';
                app.ExportWAV.Enable = 'off';
                
            % Update status
                app.StatusLabel.Text = "Filter configuration altered. Please reprocess.";
                
        end
        
        % POST-PROCESSING READY
        function void = Ready(app)
            app.PlayProcessedButton.Enable = 'on';
            app.LaunchAmpResponse.Enable = 'on';
            app.ExportWAV.Enable = 'on';
            app.PlayOriginalButton.Enable = 'on';
            app.StopButton.Enable = 'on';
            app.ProcessButton.Enable = 'on';
        end
    end
    

    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            % Load sound from user .wav file
            [filename, pathname] = uigetfile({'*.wav'},'File Selector');
            app.BrowsetoMP3EditField.Value = strcat(pathname, filename);            
            
            % Capture into y and sampling frequency
            [app.y, app.Fs] = audioread(app.BrowsetoMP3EditField.Value);
            
            % Open access
            app.PlayOriginalButton.Enable = 'on';
            app.ProcessButton.Enable = 'on';
            
            % Set status
            app.StatusLabel.Text = "File loaded. Configure filters and press process.";
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            % Kill sound
            clear sound;
            
            % Play sound
            soundsc(app.y, app.Fs);
            
            % Enable Stop button
            app.StopButton.Enable = 'on';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Kill sound
            clear sound;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
        % UPDATE STATUS
            app.StatusLabel.Text = "Processing...";            
            
            
        % RESTRICT ACCESS
            app.PlayOriginalButton.Enable = 'off';
            app.PlayProcessedButton.Enable = 'off';
            app.LaunchAmpResponse.Enable = 'off';
            app.ExportWAV.Enable = 'off';
            app.StopButton.Enable = 'off';
            app.ProcessButton.Enable = 'off';
        
            % Pause for button greys
            pause(2);
        
        
        % FILTER DESIGN

            % Predefine filter order
                ord = floor(app.OrderSlider.Value);
            
            % Predefine Freq Ranges
                low = app.LowPassEditField.Value;
                
                band_start = app.BandPassStartEditField.Value;
                band_stop  = app.BandPassStopEditField.Value;
                
                high = app.HighPassEditField.Value;
            
                
        % FILTER EXECUTION
                    
            % Low-Pass Filter
                low_design = fir1(ord, low/(app.Fs/2), 'low');
                fil_low = app.LowPassKnob.Value * filter(low_design, 1, app.y);
                
            % Band-Pass Filter
                band_design = fir1(ord, [band_start/(app.Fs/2) band_stop/(app.Fs/2)], 'bandpass');
                fil_band = app.BandPassKnob.Value * filter(band_design, 1, app.y);
                
            % High-Pass Filter
                high_design = fir1(ord, high/(app.Fs/2), 'high');
                fil_high = app.HighPassKnob.Value * filter(high_design, 1, app.y);

%             % Low pass filter
%                 app.low_filter = designfilt('lowpassfir', ...
%                 	'PassbandFrequency', low/(app.Fs/2), 'StopbandFrequency', (low+200)/(app.Fs/2), ...
%                 	'PassbandRipple', 0.5, 'StopbandAttenuation', 65, 'DesignMethod', 'kaiserwin');
%                 fil_low = filter(app.low_filter, app.y);
%             
%             % Band pass filter
%                 app.band_filter = designfilt('bandpassfir', 'FilterOrder', ord, ...
%                 	'CutoffFrequency1', band_start, 'CutoffFrequency2', band_stop, ...
%                 	'SampleRate', app.Fs);
%                 fil_band = filter(app.band_filter, app.y);
%             
%             % High pass filter
%                 app.high_filter = designfilt('highpassfir', 'StopbandFrequency', high/(app.Fs/2), ...
%                 	'PassbandFrequency', (high+200)/(app.Fs/2),'PassbandRipple', 0.5, ...
%                 	'StopbandAttenuation', 65,'DesignMethod', 'kaiserwin');
%                 fil_high = filter(app.high_filter, app.y);
            
            % Add filters
                app.yT = fil_low + fil_band + fil_high;
                
                
        % PLOT SPECTRUM
        
            % Plot original spectrum
                OriginalSpectrum = fft(app.y, app.Fs);
                OriginalSpectrum = OriginalSpectrum(20:20000);
                plot(app.SpectrumOriginal, abs(OriginalSpectrum));
                
            % Plot processed spectrum
                ProcessedSpectrum = fft(app.yT, app.Fs);
                ProcessedSpectrum = ProcessedSpectrum(20:20000);
                plot(app.SpectrumProcessed, abs(ProcessedSpectrum));
                
                
        % UPDATE STATUS
            app.StatusLabel.Text = "Processing completed.";     
                
            
        % OPEN ACCESS
            app.Ready();
                
        end

        % Button pushed function: PlayProcessedButton
        function PlayProcessedButtonPushed(app, event)
            % Kill sound
            clear sound;
            
            % Play sound
            soundsc(app.yT, app.Fs);
        end

        % Callback function
        function LaunchFilterAmplitudeResponseCheckBoxValueChanged(app, event)
            
        end

        % Button pushed function: LaunchAmpResponse
        function LaunchAmpResponsePushed(app, event)
            %   REASSIGN FILTER
            
                % Predefine filter order
                ord = floor(app.OrderSlider.Value);
                
                % Predefine Freq Ranges
                low = app.LowPassEditField.Value;
                
                band_start = app.BandPassStartEditField.Value;
                band_stop  = app.BandPassStopEditField.Value;
                
                high = app.HighPassEditField.Value;
                
                % Low pass filter
                low_filter = designfilt('lowpassfir', ...
                'PassbandFrequency', low/(app.Fs/2), 'StopbandFrequency', (low+200)/(app.Fs/2), ...
                'PassbandRipple', 0.5, 'StopbandAttenuation', 65, 'DesignMethod', 'kaiserwin');
                
                % Band pass filter
                band_filter = designfilt('bandpassfir', 'FilterOrder', ord, ...
                'CutoffFrequency1', band_start, 'CutoffFrequency2', band_stop, ...
                'SampleRate', app.Fs);
                
                % High pass filter
                high_filter = designfilt('highpassfir', 'StopbandFrequency', high/(app.Fs/2), ...
                'PassbandFrequency', (high+200)/(app.Fs/2),'PassbandRipple', 0.5, ...
                'StopbandAttenuation', 65,'DesignMethod', 'kaiserwin');
            
            
            % VISUALIZE
            
                % Launch Filter Views for System
                fvtool(low_filter);
                fvtool(band_filter);
                fvtool(high_filter);
        end

        % Button pushed function: ExportWAV
        function ExportWAVButtonPushed(app, event)
            % Save equalized file as .WAV
            filename = 'EqualizedExport.wav';
            audiowrite(filename, app.yT, app.Fs);
            
            % Update status
            app.StatusLabel.Text = "Equalized file exported successfully.";
        end

        % Value changed function: HighPassKnob
        function HighPassKnobValueChanged(app, event)
            value = app.HighPassKnob.Value;
            
            % Grey post processing buttons
            app.Changed();
        end

        % Value changed function: BandPassKnob
        function BandPassKnobValueChanged(app, event)
            value = app.BandPassKnob.Value;
            
            % Grey post processing buttons
            app.Changed();
        end

        % Value changed function: LowPassKnob
        function LowPassKnobValueChanged(app, event)
            value = app.LowPassKnob.Value;
            
            % Grey post processing buttons
            app.Changed();
        end

        % Value changed function: HighPassEditField
        function HighPassEditFieldValueChanged(app, event)
            value = app.HighPassEditField.Value;
            
            % Grey post processing buttons
            app.Changed();
        end

        % Value changed function: LowPassEditField
        function LowPassEditFieldValueChanged(app, event)
            value = app.LowPassEditField.Value;
            
            % Grey post processing buttons
            app.Changed();
        end

        % Value changed function: BandPassStartEditField
        function BandPassStartEditFieldValueChanged(app, event)
            value = app.BandPassStartEditField.Value;
            
            % Grey post processing buttons
            app.Changed();
        end

        % Value changed function: BandPassStopEditField
        function BandPassStopEditFieldValueChanged(app, event)
            value = app.BandPassStopEditField.Value;
            
            % Grey post processing buttons
            app.Changed();
        end

        % Value changed function: OrderSlider
        function OrderSliderValueChanged(app, event)
            value = app.OrderSlider.Value;
            
            % Grey post processing buttons
            app.Changed();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadWAVFilePanel = uipanel(app.UIFigure);
            app.LoadWAVFilePanel.Title = 'Load WAV File';
            app.BrowseButton = uibutton(app.LoadWAVFilePanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse ...';
            app.BrowsetoMP3EditField = uieditfield(app.LoadWAVFilePanel, 'text');
            app.FilterConfigurationPanel = uipanel(app.UIFigure);
            app.FilterConfigurationPanel.Title = 'Filter Configuration';
            app.LowPassKnobLabel = uilabel(app.FilterConfigurationPanel);
            app.LowPassKnobLabel.Text = 'Low-Pass';
            app.LowPassKnob = uiknob(app.FilterConfigurationPanel, 'continuous');
            app.LowPassKnob.Limits = [0 4];
            app.LowPassKnob.ValueChangedFcn = createCallbackFcn(app, @LowPassKnobValueChanged, true);
            app.LowPassKnob.Value = 1;
            app.BandPassKnobLabel = uilabel(app.FilterConfigurationPanel);
            app.BandPassKnobLabel.Text = 'Band-Pass';
            app.BandPassKnob = uiknob(app.FilterConfigurationPanel, 'continuous');
            app.BandPassKnob.Limits = [0 4];
            app.BandPassKnob.ValueChangedFcn = createCallbackFcn(app, @BandPassKnobValueChanged, true);
            app.BandPassKnob.Value = 1;
            app.HighPassLabel = uilabel(app.FilterConfigurationPanel);
            app.HighPassLabel.Text = 'High-Pass';
            app.HighPassKnob = uiknob(app.FilterConfigurationPanel, 'continuous');
            app.HighPassKnob.Limits = [0 4];
            app.HighPassKnob.ValueChangedFcn = createCallbackFcn(app, @HighPassKnobValueChanged, true);
            app.HighPassKnob.Value = 1;
            app.LowPassStopBandEditFieldLabel = uilabel(app.FilterConfigurationPanel);
            app.LowPassStopBandEditFieldLabel.Text = 'Low-Pass Stop Band';
            app.LowPassEditField = uieditfield(app.FilterConfigurationPanel, 'numeric');
            app.LowPassEditField.Limits = [20 400];
            app.LowPassEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassEditFieldValueChanged, true);
            app.LowPassEditField.Value = 400;
            app.BandPassStartBandEditFieldLabel = uilabel(app.FilterConfigurationPanel);
            app.BandPassStartBandEditFieldLabel.Text = 'Band-Pass Start Band';
            app.BandPassStartEditField = uieditfield(app.FilterConfigurationPanel, 'numeric');
            app.BandPassStartEditField.Limits = [500 3000];
            app.BandPassStartEditField.ValueChangedFcn = createCallbackFcn(app, @BandPassStartEditFieldValueChanged, true);
            app.BandPassStartEditField.Value = 500;
            app.BandPassStartBandEditField_2Label = uilabel(app.FilterConfigurationPanel);
            app.BandPassStartBandEditField_2Label.Text = 'Band-Pass Start Band';
            app.BandPassStopEditField = uieditfield(app.FilterConfigurationPanel, 'numeric');
            app.BandPassStopEditField.Limits = [500 3000];
            app.BandPassStopEditField.ValueChangedFcn = createCallbackFcn(app, @BandPassStopEditFieldValueChanged, true);
            app.BandPassStopEditField.Value = 3000;
            app.HighPassStartBandEditFieldLabel = uilabel(app.FilterConfigurationPanel);
            app.HighPassStartBandEditFieldLabel.Text = 'High-Pass Start Band';
            app.HighPassEditField = uieditfield(app.FilterConfigurationPanel, 'numeric');
            app.HighPassEditField.Limits = [4000 20000];
            app.HighPassEditField.ValueChangedFcn = createCallbackFcn(app, @HighPassEditFieldValueChanged, true);
            app.HighPassEditField.Value = 4000;
            app.FrequencySpecificationsLabel = uilabel(app.FilterConfigurationPanel);
            app.FrequencySpecificationsLabel.Text = 'Frequency Specifications:';
            app.WeightSpecificationsLabel = uilabel(app.FilterConfigurationPanel);
            app.WeightSpecificationsLabel.Text = 'Weight Specifications:';
            app.OrderSliderLabel = uilabel(app.FilterConfigurationPanel);
            app.OrderSliderLabel.Text = 'Order';
            app.OrderSlider = uislider(app.FilterConfigurationPanel);
            app.OrderSlider.Limits = [16 96];
            app.OrderSlider.MajorTicks = [16 32 48 64 80 96];
            app.OrderSlider.MajorTickLabels = {'16', '32', '48', '64', '80', '96'};
            app.OrderSlider.ValueChangedFcn = createCallbackFcn(app, @OrderSliderValueChanged, true);
            app.OrderSlider.Value = 16;
            app.FilterOrderSpecificationsLabel = uilabel(app.FilterConfigurationPanel);
            app.FilterOrderSpecificationsLabel.Text = 'Filter Order Specifications:';
            app.FrequencySpectrumPanel = uipanel(app.UIFigure);
            app.FrequencySpectrumPanel.Title = 'Frequency Spectrum';
            app.SpectrumOriginal = uiaxes(app.FrequencySpectrumPanel);
            title(app.SpectrumOriginal, 'Original Spectrum')
            xlabel(app.SpectrumOriginal, 'Frequency (Hz)')
            ylabel(app.SpectrumOriginal, 'Amplitude')
            app.SpectrumProcessed = uiaxes(app.FrequencySpectrumPanel);
            title(app.SpectrumProcessed, 'Processed Spectrum')
            xlabel(app.SpectrumProcessed, 'Frequency (Hz)')
            ylabel(app.SpectrumProcessed, 'Amplitude')
            app.ListeningExperiencePanel = uipanel(app.UIFigure);
            app.ListeningExperiencePanel.Title = 'Listening Experience';
            app.PlayOriginalButton = uibutton(app.ListeningExperiencePanel, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Enable = 'off';
            app.PlayOriginalButton.Text = 'Play Original';
            app.StopButton = uibutton(app.ListeningExperiencePanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.ProcessButton = uibutton(app.ListeningExperiencePanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process ...';
            app.PlayProcessedButton = uibutton(app.ListeningExperiencePanel, 'push');
            app.PlayProcessedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayProcessedButtonPushed, true);
            app.PlayProcessedButton.Enable = 'off';
            app.PlayProcessedButton.Text = 'Play Processed';
            app.PostProcessingOptionsPanel = uipanel(app.UIFigure);
            app.PostProcessingOptionsPanel.Title = 'Post Processing Options';
            app.LaunchAmpResponse = uibutton(app.PostProcessingOptionsPanel, 'push');
            app.LaunchAmpResponse.ButtonPushedFcn = createCallbackFcn(app, @LaunchAmpResponsePushed, true);
            app.LaunchAmpResponse.Enable = 'off';
            app.LaunchAmpResponse.Text = 'Launch Filter Amplitude Responses';
            app.ExportWAV = uibutton(app.PostProcessingOptionsPanel, 'push');
            app.ExportWAV.ButtonPushedFcn = createCallbackFcn(app, @ExportWAVButtonPushed, true);
            app.ExportWAV.Enable = 'off';
            app.ExportWAV.Text = 'Export Equalized WAV File';
            app.ProgramStatusPanel = uipanel(app.UIFigure);
            app.ProgramStatusPanel.Title = 'Program Status';
            app.StatusLabel = uilabel(app.ProgramStatusPanel);
            app.StatusLabel.Text = 'Waiting for WAV file input...';
            app.ECE460EqualizerLabel = uilabel(app.UIFigure);
            app.ECE460EqualizerLabel.Text = 'ECE 460 - Equalizer*';
            app.poweredbyMATLABandsleeplessnightsLabel = uilabel(app.UIFigure);
            app.poweredbyMATLABandsleeplessnightsLabel.Text = '*powered by MATLAB and sleepless nights.';
            app.DialBuchananSamboluLabel = uilabel(app.UIFigure);
            app.DialBuchananSamboluLabel.Text = 'Dial • Buchanan • Sambolu';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EqualizerXtra

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end